classdef TimingModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EpochingModuleUIFigure          matlab.ui.Figure
        LoadDataButton                  matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        TabGroup2                       matlab.ui.container.TabGroup
        BasicSignalProcessingTab        matlab.ui.container.Tab
        CleanPlotButton                 matlab.ui.control.Button
        ResetPreprocessingButton        matlab.ui.control.Button
        StoreButton                     matlab.ui.control.Button
        UITablePreprocessing            matlab.ui.control.Table
        PreprocessingNameEditFieldLabel  matlab.ui.control.Label
        PreprocessingNameEditField      matlab.ui.control.EditField
        DemeanPanel                     matlab.ui.container.Panel
        PlotDemeanButton                matlab.ui.control.Button
        SetDemeanButton                 matlab.ui.control.Button
        DetrendPanel                    matlab.ui.container.Panel
        PlotDetrendButton               matlab.ui.control.Button
        SetDetrendButton                matlab.ui.control.Button
        DifferencePanel                 matlab.ui.container.Panel
        PlotDifferenceButton            matlab.ui.control.Button
        SetDifferenceButton             matlab.ui.control.Button
        OrderDifferenceSpinnerLabel     matlab.ui.control.Label
        OrderDifferenceSpinner          matlab.ui.control.Spinner
        FlipPanel                       matlab.ui.container.Panel
        SetFlipButton                   matlab.ui.control.Button
        PlotFlipButton                  matlab.ui.control.Button
        GradientPanel                   matlab.ui.container.Panel
        PlotGradientButton              matlab.ui.control.Button
        SetGradientButton               matlab.ui.control.Button
        OrderGradientSpinnerLabel       matlab.ui.control.Label
        OrderGradientSpinner            matlab.ui.control.Spinner
        HaarDecompPanel                 matlab.ui.container.Panel
        PlotHaarButton                  matlab.ui.control.Button
        SetHaarButton                   matlab.ui.control.Button
        OrderHaarSpinnerLabel           matlab.ui.control.Label
        OrderHaarSpinner                matlab.ui.control.Spinner
        TimingTab                       matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        ThresholdTab                    matlab.ui.container.Tab
        ThresholdingButtonGroup         matlab.ui.container.ButtonGroup
        MeanButton                      matlab.ui.control.RadioButton
        CustomButton                    matlab.ui.control.RadioButton
        StdEditFieldLabel               matlab.ui.control.Label
        StdEditField                    matlab.ui.control.NumericEditField
        CustomThresholdEditField        matlab.ui.control.NumericEditField
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        FirstPointPerCrossingCheckBox   matlab.ui.control.CheckBox
        UIAxesThreshold                 matlab.ui.control.UIAxes
        FindPeaksTab                    matlab.ui.container.Tab
        NumberofPeaksCheckBox           matlab.ui.control.CheckBox
        MinimumPeakHeightCheckBox       matlab.ui.control.CheckBox
        MinimumPeakProminenceCheckBox   matlab.ui.control.CheckBox
        ThresholdCheckBox               matlab.ui.control.CheckBox
        MinimumPeakDistanceCheckBox     matlab.ui.control.CheckBox
        MinimumPeakWidthCheckBox        matlab.ui.control.CheckBox
        MaximumPeakWidthCheckBox        matlab.ui.control.CheckBox
        NumberofPeaksEditField          matlab.ui.control.NumericEditField
        MinimumPeakHeightEditField      matlab.ui.control.NumericEditField
        MinimumPeakProminenceEditField  matlab.ui.control.NumericEditField
        ThresholdEditField              matlab.ui.control.NumericEditField
        MinimumPeakDistance             matlab.ui.control.NumericEditField
        MinimumPeakWidth                matlab.ui.control.NumericEditField
        MaximumPeakWidth                matlab.ui.control.NumericEditField
        WaveletsTab                     matlab.ui.container.Tab
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        GenerateTemplateButton          matlab.ui.control.Button
        WaveletVectorEditFieldLabel     matlab.ui.control.Label
        WaveletVectorEditField          matlab.ui.control.EditField
        UIAxesWavelet                   matlab.ui.control.UIAxes
        CommentsTab                     matlab.ui.container.Tab
        UITableComments                 matlab.ui.control.Table
        RemoveCommentButton             matlab.ui.control.Button
        ResetCommentsButton             matlab.ui.control.Button
        LoadCommentsButton              matlab.ui.control.Button
        CommentSearchBar                matlab.ui.control.EditField
        MarkerControlPanel              matlab.ui.container.Panel
        MarkerDropDown                  matlab.ui.control.DropDown
        AddMarkerButton                 matlab.ui.control.Button
        RemoveMarkerButton              matlab.ui.control.Button
        MarkerLabelEditField            matlab.ui.control.EditField
        ShowHideMarkerButton            matlab.ui.control.Button
        ExportMarkerButton              matlab.ui.control.Button
        MarkerColorButton               matlab.ui.control.Button
        ColorIndicator                  matlab.ui.control.Label
        TimingVectorLengthEditFieldLabel  matlab.ui.control.Label
        TimingVectorLengthEditField     matlab.ui.control.NumericEditField
        RemapMarkersButton              matlab.ui.control.Button
        MarkerListPanel                 matlab.ui.container.Panel
        MarkerListBox                   matlab.ui.control.ListBox
        DatasetsDropDownLabel           matlab.ui.control.Label
        DatasetsDropDown                matlab.ui.control.DropDown
        RefreshButton                   matlab.ui.control.Button
        InputSampleRateEditFieldLabel   matlab.ui.control.Label
        InputSampleRateEditField        matlab.ui.control.NumericEditField
        OutputSampleRateEditFieldLabel  matlab.ui.control.Label
        OutputSampleRateEditField       matlab.ui.control.NumericEditField
        DeleteButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dataFilename
        dataFilepath
        dataFileExt
        
        commentFilename
        commentFilepath
        
        Comments
        CommentTimings
        
        RawData
        ProcessedData
        PlottedData
        
        ProcessingStepIndex = 1
        
        PolyFit
        Order = 1
        Curve
        
        tmpThreshold
        
        currentTimingVectorMarker
        currentTimingVectorMethod
        currentTimingVector
        storedTimingVectors
        
        PreprocessedTableIndex
        CommentTableIndex
        
        RGB = [1 0 0];
        
        SelectionWindow
        
        Wavelet
        
        SavePath
        
    end
    
    methods (Access = private)
        
        function selectDataset(app)
            [file,path] = uigetfile('*.*');
            figure(app.EpochingModuleUIFigure)
            if file==0
                return
            end
            resetData(app)
            [app.dataFilepath,app.dataFilename,app.dataFileExt] = fileparts(fullfile(path,file));
            if ~isempty(regexp(app.dataFileExt,'n(e|s)(v|\d)$','once'))
                d=uiprogressdlg(app.EpochingModuleUIFigure,'Title','Loading Blackrock Data','Indeterminate','on');
                NS5 = openNSx(fullfile(app.dataFilepath,[app.dataFilename,'.ns5']),'noread');
                app.InputSampleRateEditField.Value = NS5.MetaTags.SamplingFreq;
                tmp = {NS5.ElectrodesInfo.Label}';
                tmp = regexprep(tmp,'\W','');
                app.DropDown.Items = tmp;
                app.DropDown.ItemsData = 1:length(app.DropDown.Items);
                close(d)
                loadData(app)
                if exist(fullfile(app.dataFilepath,[app.dataFilename,'.nev']),'file')
                    loadComments(app);
                end
            elseif ~isempty(regexp(app.dataFileExt,'mat$','once'))
                d=uiprogressdlg(app.EpochingModuleUIFigure,'Title','Loading Mat-File Data','Indeterminate','on');
                dataObj = matfile(fullfile(app.dataFilepath,[app.dataFilename,app.dataFileExt]));
                numChan = size(dataObj.Signal,1);
                app.DropDown.Items = cellstr(string(1:numChan));
                app.DropDown.ItemsData = 1:numChan;
                close(d)
                loadData(app)
            end
        end
        
        function loadData(app)
            d=uiprogressdlg(app.EpochingModuleUIFigure,'Title',sprintf('Loading Signal: %s',app.DropDown.Items{app.DropDown.Value}),'Indeterminate','on');
            if ~isempty(regexp(app.dataFileExt,'n(e|s)(v|\d)$','once'))
                NS5 = openNSx(fullfile(app.dataFilepath,[app.dataFilename,'.ns5']),['c:' int2str(app.DropDown.Value)]);
                NS5 = processSingleNSx(NS5);
                app.RawData = double(NS5.Data);
                app.ProcessedData = struct();
                app.ProcessedData(1).Label = 'Raw';
                app.ProcessedData(1).Data = app.RawData;
                app.ProcessedData(1).Steps = {};
                app.PlottedData = app.ProcessedData(1).Data;
                app.DatasetsDropDown.Items = {app.ProcessedData(1).Label};
                app.DatasetsDropDown.ItemsData = 1:length(app.DatasetsDropDown.Items);
                close(d)
            elseif ~isempty(regexp(app.dataFileExt,'mat$','once'))
                dataObj = matfile(fullfile(app.dataFilepath,[app.dataFilename,app.dataFileExt]));
                app.RawData = double(dataObj.Signal(app.DropDown.Value,:));
                app.ProcessedData = struct();
                app.ProcessedData(1).Label = 'Raw';
                app.ProcessedData(1).Data = app.RawData;
                app.ProcessedData(1).Steps = {};
                app.PlottedData = app.ProcessedData(1).Data;
                app.DatasetsDropDown.Items = {app.ProcessedData(1).Label};
                app.DatasetsDropDown.ItemsData = 1:length(app.DatasetsDropDown.Items);
                close(d)
            end
            plot(app.UIAxes,app.PlottedData,'PickableParts','none')
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value},' - ',app.DatasetsDropDown.Items{app.DatasetsDropDown.Value}])
            xlabel(app.UIAxes,'Time')
            if ~isempty(app.storedTimingVectors)
                hold(app.UIAxes,'on')
                for i=1:length(app.storedTimingVectors)
                    plot(app.UIAxes,app.storedTimingVectors(i).Data,app.PlottedData(app.storedTimingVectors(i).Data),'LineStyle','none','Marker',app.storedTimingVectors(i).Marker,'MarkerEdgeColor',app.storedTimingVectors(i).MarkerEdgeColor);
                end
                hold(app.UIAxes,'off')
            end
            app.UITablePreprocessing.Data={};
        end
        
        function loadComments(app)
            NEV = openNEV(fullfile(app.dataFilepath,[app.dataFilename,'.nev']),'nosave');
            app.Comments = string(NEV.Data.Comments.Text);
            app.Comments = regexprep(app.Comments,'\W*$','');
            app.CommentTimings = NEV.Data.Comments.TimeStamp;
            app.CommentTimings = app.CommentTimings(:);
            app.UITableComments.Data = table(app.Comments,app.CommentTimings);
        end
        
        
        function resetData(app)
            app.dataFilename = [];
            app.dataFilepath = [];
            
            app.commentFilename = [];
            app.commentFilepath = [];
            
            app.Comments = [];
            app.CommentTimings = [];
            
            app.RawData = [];
            app.ProcessedData = [];
            app.PlottedData = [];
            
            app.currentTimingVectorMarker = [];
            app.currentTimingVectorMethod = [];
            app.currentTimingVector = [];
            app.storedTimingVectors = [];
            
            app.PreprocessedTableIndex = [];
            app.CommentTableIndex = [];
            
            app.UITableComments.Data = [];
            app.MarkerListBox.Items = {};
        end
        
        
        function polyFitSignal(app)
            x=1:length(app.PlottedData);
            x=x(:);
            app.PolyFit = polyfit(x,double(app.PlottedData(:)),app.Order);
            app.Curve = polyval(app.PolyFit,x);
        end
        
        
        function createTimingVector(app)
            switch app.TabGroup.SelectedTab.Title
                case 'Threshold'
                    if app.MeanButton.Value
                        mu = mean(app.PlottedData);
                        stdev = std(app.PlottedData);
                        app.tmpThreshold = mu+app.StdEditField.Value*stdev;
                        app.CustomThresholdEditField.Value = app.tmpThreshold;
                        methodName = 'Mean+Std';
                    elseif app.CustomButton.Value
                        app.tmpThreshold = app.CustomThresholdEditField.Value;
                        methodName = 'Custom';
                    end
                    switch app.TypeDropDown.Value
                        case 'Absolute'
                            app.currentTimingVector = find(abs(app.PlottedData)>app.tmpThreshold);
                        case 'Positive'
                            app.currentTimingVector = find(app.PlottedData>app.tmpThreshold);
                        case 'Negative'
                            app.currentTimingVector = find(app.PlottedData<app.tmpThreshold);
                    end
                    if app.FirstPointPerCrossingCheckBox.Value
                        tmp = find(diff(app.currentTimingVector)>1);
                        app.currentTimingVector = app.currentTimingVector([1 tmp+1]);
                    end
                    thresholdPlot(app);
                    if isempty(app.MarkerLabelEditField.Value)
                        app.currentTimingVectorMethod = [app.DatasetsDropDown.Items{app.DatasetsDropDown.Value},' - ',app.TabGroup.SelectedTab.Title,': ',methodName,'-',app.TypeDropDown.Value];
                    end
                    
                case 'FindPeaks'
                    d = uiprogressdlg(app.EpochingModuleUIFigure,'Title','Running FindPeaks','Indeterminate','on');
                    if app.NumberofPeaksCheckBox.Value
                        NPeaks = app.NumberofPeaksEditField.Value;
                    else
                        NPeaks = [];
                    end
                    if app.MinimumPeakHeightCheckBox.Value
                        MinPeakHeight = app.MinimumPeakHeightEditField.Value;
                    else
                        MinPeakHeight = -inf;
                    end
                    if app.MinimumPeakProminenceCheckBox.Value
                        MinPeakProminence = app.MinimumPeakProminenceEditField.Value;
                    else
                        MinPeakProminence = 0;
                    end
                    if app.ThresholdCheckBox.Value
                        Threshold = app.ThresholdEditField.Value;
                    else
                        Threshold = 0;
                    end
                    if app.MinimumPeakDistanceCheckBox.Value
                        MinPeakDistance = app.MinimumPeakDistance.Value;
                    else
                        MinPeakDistance = 0;
                    end
                    if app.MinimumPeakWidthCheckBox.Value
                        MinPeakWidth = app.MinimumPeakWidth.Value;
                    else
                        MinPeakWidth = 0;
                    end
                    if app.MaximumPeakWidthCheckBox.Value
                        MaxPeakWidth = app.MaximumPeakWidth.Value;
                    else
                        MaxPeakWidth = Inf;
                    end
                    
                    [~,app.currentTimingVector] = findpeaks(app.PlottedData,'NPeaks',NPeaks,'MinPeakHeight',MinPeakHeight,'MinPeakProminence',MinPeakProminence,'Threshold',Threshold,'MinPeakDistance',MinPeakDistance,'MinPeakWidth',MinPeakWidth,'MaxPeakWidth',MaxPeakWidth);
                    close(d)
                    if isempty(app.MarkerLabelEditField.Value)
                        app.currentTimingVectorMethod = [app.DatasetsDropDown.Items{app.DatasetsDropDown.Value},' - ',app.TabGroup.SelectedTab.Title];
                    end
                    
                case 'Wavelets'
                    switch app.MethodDropDown.Value
                        case 'Manual'
                            app.Wavelet = str2double(regexp(app.WaveletVectorEditField.Value,',| +','split'));
                            waveletPlot(app);
                        case 'Template'
                    end
                    tmp = conv(app.PlottedData/range(app.PlottedData),flip(app.Wavelet/range(app.Wavelet)),'same');
                    tmp(tmp<0.5)=0;
                    tmp=diff(tmp);
                    app.currentTimingVector = find(tmp(1:end-1)>=0 & tmp(2:end)<0)+1;
                    if isempty(app.MarkerLabelEditField.Value)
                        app.currentTimingVectorMethod = [app.DatasetsDropDown.Items{app.DatasetsDropDown.Value},' - ',app.TabGroup.SelectedTab.Title,': ',app.MethodDropDown.Value];
                    end
                    
                case 'Comments'
                    app.currentTimingVector = app.UITableComments.Data{:,2};
                    if isempty(app.currentTimingVector)
                        return
                    end
                    if isempty(app.MarkerLabelEditField.Value)
                        app.currentTimingVectorMethod = [app.DatasetsDropDown.Items{app.DatasetsDropDown.Value},' - ',app.TabGroup.SelectedTab.Title];
                    end
                    
            end
            app.TimingVectorLengthEditField.Value = length(app.currentTimingVector);
            refreshPlotMarker(app)
        end
        
        
        function waveletPlot(app)
            plot(app.UIAxesWavelet,app.Wavelet,'PickableParts','none')
        end
        
        
        function thresholdPlot(app)
            plot(app.UIAxesThreshold,app.PlottedData,'PickableParts','none')
            xlabel(app.UIAxesThreshold,'Time')
            hold(app.UIAxesThreshold,'on')
            plot(app.UIAxesThreshold,(1:length(app.PlottedData)),repmat(app.tmpThreshold,[1,length(app.PlottedData)]),'r-')
            hold(app.UIAxesThreshold,'off')
        end
        
        
        function refreshPlotMarker(app)
            if length(app.UIAxes.Children)>(1+length(app.storedTimingVectors))
                delete(app.UIAxes.Children(1:end-(1+length(app.storedTimingVectors))))
            end
            if isempty(app.currentTimingVectorMarker)
                app.currentTimingVectorMarker = {'+',[0 0 0]};
            end
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.currentTimingVector,app.PlottedData(app.currentTimingVector),'LineStyle','none','Marker',app.currentTimingVectorMarker{1},'MarkerEdgeColor',app.currentTimingVectorMarker{2});
            hold(app.UIAxes,'off')
        end
        
        
        function selectPoints(app,~)
            app.SelectionWindow = drawrectangle(app.UIAxes);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EpochingModuleUIFigure.Visible = 'off';
            movegui(app.EpochingModuleUIFigure,'center')
            app.EpochingModuleUIFigure.Visible = 'on';
            
            app.ProcessedData = struct([]);
            app.storedTimingVectors = struct([]);
            selectDataset(app);

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            selectDataset(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            loadData(app);
        end

        % Value changed function: DatasetsDropDown
        function DatasetsDropDownValueChanged(app, event)
            if app.DatasetsDropDown.Value == 1
                app.DeleteButton.Enable = 0;
            else
                app.DeleteButton.Enable = 1;
            end
            app.PlottedData = app.ProcessedData(app.DatasetsDropDown.Value).Data;
            app.UITablePreprocessing.Data = app.ProcessedData(app.DatasetsDropDown.Value).Steps;
            plot(app.UIAxes,app.PlottedData,'PickableParts','none')
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value},' - ',app.DatasetsDropDown.Items{app.DatasetsDropDown.Value}])
            xlabel(app.UIAxes,'Time')
            if ~isempty(app.storedTimingVectors)
                hold(app.UIAxes,'on')
                for i=1:length(app.storedTimingVectors)
                    plot(app.UIAxes,app.storedTimingVectors(i).Data,app.PlottedData(app.storedTimingVectors(i).Data),'LineStyle','none','Marker',app.storedTimingVectors(i).Marker,'MarkerEdgeColor',app.storedTimingVectors(i).MarkerEdgeColor);
                end
                hold(app.UIAxes,'off')
            end
            app.UITablePreprocessing.Data = app.ProcessedData(app.DatasetsDropDown.Value).Steps;
            if strcmp(app.TabGroup2.SelectedTab.Title,'Timing')
                createTimingVector(app);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            tmp = find(strcmp({app.UIAxes.Children.Visible},'on')==1);
            set(app.UIAxes.Children(tmp),'Visible',0)
            set(app.UIAxes.Children(tmp),'Visible',1)
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            switch app.TabGroup2.SelectedTab.Title
                case 'Basic Signal Processing'
                    if length(app.UIAxes.Children)>(1+length(app.storedTimingVectors))
                        delete(app.UIAxes.Children(1:end-(1+length(app.storedTimingVectors))))
                    end
                case 'Timing'
                    createTimingVector(app)
            end
        end

        % Button pushed function: PlotDemeanButton
        function PlotDemeanButtonPushed(app, event)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.PlottedData-mean(app.PlottedData),'r-')
            hold(app.UIAxes,'off')
            set(app.UIAxes.Children(1),'Tag','tmp')
        end

        % Button pushed function: SetDemeanButton
        function SetDemeanButtonPushed(app, event)
            app.PlottedData = app.PlottedData-mean(app.PlottedData);
            plot(app.UIAxes,app.PlottedData)
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value},' - Demean'])
            xlabel(app.UIAxes,'Time')
            polyFitSignal(app)
            app.UITablePreprocessing.Data{length(app.UITablePreprocessing.Data)+1,1} = 'Demean';
        end

        % Button pushed function: PlotDetrendButton
        function PlotDetrendButtonPushed(app, event)
            polyFitSignal(app)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,double(app.PlottedData(:))-app.Curve,'r-')
            hold(app.UIAxes,'off')
            set(app.UIAxes.Children(1),'Tag','tmp')
        end

        % Button pushed function: SetDetrendButton
        function SetDetrendButtonPushed(app, event)
            polyFitSignal(app)
            app.PlottedData = double(app.PlottedData)-app.Curve;
            plot(app.UIAxes,app.PlottedData)
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value},' - Detrend'])
            xlabel(app.UIAxes,'Time')
            polyFitSignal(app)
            app.UITablePreprocessing.Data{length(app.UITablePreprocessing.Data)+1,1} = 'Detrend';
        end

        % Button pushed function: PlotFlipButton
        function PlotFlipButtonPushed(app, event)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,-app.PlottedData,'r-')
            hold(app.UIAxes,'off')
            set(app.UIAxes.Children(1),'Tag','tmp')
        end

        % Button pushed function: SetFlipButton
        function SetFlipButtonPushed(app, event)
            app.PlottedData = -app.PlottedData;
            plot(app.UIAxes,app.PlottedData)
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value},' - Flipped'])
            xlabel(app.UIAxes,'Time')
            polyFitSignal(app)
            app.UITablePreprocessing.Data{length(app.UITablePreprocessing.Data)+1,1} = 'Flipped';
        end

        % Button pushed function: PlotDifferenceButton
        function PlotDifferenceButtonPushed(app, event)
            tmp = app.PlottedData;
            for i=1:app.OrderDifferenceSpinner.Value
                tmp = diff(tmp);
            end
            hold(app.UIAxes,'on')
            plot(app.UIAxes,tmp,'r-')
            hold(app.UIAxes,'off')
            set(app.UIAxes.Children(1),'Tag','tmp')
        end

        % Button pushed function: SetDifferenceButton
        function SetDifferenceButtonPushed(app, event)
            tmp = app.PlottedData;
            for i=1:app.OrderDifferenceSpinner.Value
                tmp = diff(tmp);
            end
            app.PlottedData = tmp;
            plot(app.UIAxes,app.PlottedData)
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value},' - Differencing-Order ',int2str(app.OrderDifferenceSpinner.Value)])
            xlabel(app.UIAxes,'Time')
            polyFitSignal(app)
            app.UITablePreprocessing.Data{length(app.UITablePreprocessing.Data)+1,1} = ['Differencing-Order ',int2str(app.OrderDifferenceSpinner.Value)];
        end

        % Button pushed function: PlotGradientButton
        function PlotGradientButtonPushed(app, event)
            tmp = double(app.PlottedData);
            for i=1:app.OrderGradientSpinner.Value
                tmp = gradient(tmp);
            end
            hold(app.UIAxes,'on')
            plot(app.UIAxes,tmp,'r-')
            hold(app.UIAxes,'off')
            set(app.UIAxes.Children(1),'Tag','tmp')
        end

        % Button pushed function: SetGradientButton
        function SetGradientButtonPushed(app, event)
            tmp = double(app.PlottedData);
            for i=1:app.OrderGradientSpinner.Value
                tmp = gradient(tmp);
            end
            app.PlottedData = tmp;
            plot(app.UIAxes,app.PlottedData)
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value},' - Gradient-Order ',int2str(app.OrderGradientSpinner.Value)])
            xlabel(app.UIAxes,'Time')
            polyFitSignal(app)
            app.UITablePreprocessing.Data{length(app.UITablePreprocessing.Data)+1,1} = ['Gradient-Order ',int2str(app.OrderGradientSpinner.Value)];
        end

        % Button pushed function: PlotHaarButton
        function PlotHaarButtonPushed(app, event)
            [h,hh] = haart(app.PlottedData,'integer');
            tmpHaar = ihaart(h,hh,app.OrderHaarSpinner.Value,'integer');
            hold(app.UIAxes,'on')
            plot(app.UIAxes,tmpHaar,'r-')
            hold(app.UIAxes,'off')
            set(app.UIAxes.Children(1),'Tag','tmp')
        end

        % Button pushed function: SetHaarButton
        function SetHaarButtonPushed(app, event)
            [h,hh] = haart(app.PlottedData,'integer');
            tmpHaar = ihaart(h,hh,app.OrderHaarSpinner.Value,'integer');
            app.PlottedData = tmpHaar;
            plot(app.UIAxes,app.PlottedData)
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value},' - HaarDecomp-Order ',int2str(app.OrderHaarSpinner.Value)])
            xlabel(app.UIAxes,'Time')
            polyFitSignal(app)
            app.UITablePreprocessing.Data{length(app.UITablePreprocessing.Data)+1,1} = ['HaarDecomp-Order ',int2str(app.OrderHaarSpinner.Value)];
        end

        % Button pushed function: StoreButton
        function StoreButtonPushed(app, event)
        index = length(app.ProcessedData);
        if isempty(app.PreprocessingNameEditField.Value)
            tmpName = app.UITablePreprocessing.Data{end};
            newName = tmpName;
            i=1;
            while ~all(cellfun(@isempty,regexp({app.ProcessedData(:).Label},newName,'match')))
                newName = sprintf('%s_%02d',tmpName,i);
                i=i+1;
            end
            app.ProcessedData(index+1).Label = newName;
        else
            newName = app.PreprocessingNameEditField.Value;
            app.ProcessedData(index+1).Label = newName;
        end
        app.ProcessedData(index+1).Data = app.PlottedData;
        app.ProcessedData(index+1).Steps = app.UITablePreprocessing.Data;
        app.DatasetsDropDown.Items = [app.DatasetsDropDown.Items,newName];
        app.DatasetsDropDown.ItemsData = 1:length(app.DatasetsDropDown.Items);
        app.DatasetsDropDown.Value = length(app.DatasetsDropDown.Items);
        end

        % Button pushed function: ResetPreprocessingButton
        function ResetPreprocessingButtonPushed(app, event)
            app.DatasetsDropDown.Value = 1;
            DatasetsDropDownValueChanged(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.ProcessedData(app.DatasetsDropDown.Value)=[];
            app.DatasetsDropDown.Value = app.DatasetsDropDown.Value - 1;
            app.DatasetsDropDown.Items = {app.ProcessedData(:).Label};
            app.DatasetsDropDown.ItemsData = 1:length(app.DatasetsDropDown.Items);
            DatasetsDropDownValueChanged(app);
        end

        % Button pushed function: CleanPlotButton
        function CleanPlotButtonPushed(app, event)
            index = ~cellfun(@isempty,regexp(get(app.UIAxes.Children,'Tag'),'tmp'));
            delete(app.UIAxes.Children(index))
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            createTimingVector(app)
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            createTimingVector(app)
        end

        % Selection changed function: ThresholdingButtonGroup
        function ThresholdingButtonGroupSelectionChanged(app, event)
            createTimingVector(app)
        end

        % Value changed function: StdEditField
        function StdEditFieldValueChanged(app, event)
            createTimingVector(app)
        end

        % Value changed function: CustomThresholdEditField
        function CustomThresholdEditFieldValueChanged(app, event)
            createTimingVector(app)
        end

        % Value changed function: FirstPointPerCrossingCheckBox
        function FirstPointPerCrossingCheckBoxValueChanged(app, event)
            createTimingVector(app)
        end

        % Value changed function: NumberofPeaksCheckBox
        function NumberofPeaksCheckBoxValueChanged(app, event)
            createTimingVector(app);
        end

        % Value changed function: MinimumPeakHeightCheckBox
        function MinimumPeakHeightCheckBoxValueChanged(app, event)
            createTimingVector(app);
        end

        % Value changed function: MinimumPeakProminenceCheckBox
        function MinimumPeakProminenceCheckBoxValueChanged(app, event)
            createTimingVector(app);
        end

        % Value changed function: ThresholdCheckBox
        function ThresholdCheckBoxValueChanged(app, event)
            createTimingVector(app)
        end

        % Value changed function: MinimumPeakDistanceCheckBox
        function MinimumPeakDistanceCheckBoxValueChanged(app, event)
            createTimingVector(app);
        end

        % Value changed function: MinimumPeakWidthCheckBox
        function MinimumPeakWidthCheckBoxValueChanged(app, event)
            createTimingVector(app);
        end

        % Value changed function: MaximumPeakWidthCheckBox
        function MaximumPeakWidthCheckBoxValueChanged(app, event)
            createTimingVector(app);
        end

        % Value changed function: NumberofPeaksEditField
        function NumberofPeaksEditFieldValueChanged(app, event)
            if app.NumberofPeaksCheckBox.Value
                createTimingVector(app);
            end
        end

        % Value changed function: MinimumPeakHeightEditField
        function MinimumPeakHeightEditFieldValueChanged(app, event)
            if app.MinimumPeakHeightCheckBox.Value
                createTimingVector(app);
            end
        end

        % Value changed function: MinimumPeakProminenceEditField
        function MinimumPeakProminenceEditFieldValueChanged(app, event)
            if app.MinimumPeakProminenceCheckBox.Value
                createTimingVector(app);
            end
        end

        % Value changed function: MinimumPeakDistance
        function MinimumPeakDistanceValueChanged(app, event)
            if app.MinimumPeakDistanceCheckBox.Value
                createTimingVector(app);
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            if app.ThresholdCheckBox.Value
                createTimingVector(app)
            end
        end

        % Value changed function: MinimumPeakWidth
        function MinimumPeakWidthValueChanged(app, event)
            if app.MinimumPeakWidthCheckBox.Value
                createTimingVector(app);
            end
        end

        % Value changed function: MaximumPeakWidth
        function MaximumPeakWidthValueChanged(app, event)
            if app.MaximumPeakWidthCheckBox.Value
                createTimingVector(app);
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            switch app.MethodDropDown.Value
                case 'Manual'
                    createTimingVector(app);
                case 'Template'
                    app.GenerateTemplateButton.Enable = 1;
                    plot(app.UIAxesWavelet,app.PlottedData)
            end
        end

        % Value changed function: WaveletVectorEditField
        function WaveletVectorEditFieldValueChanged(app, event)
            app.Wavelet = app.WaveletVectorEditField.Value;
            createTimingVector(app);
        end

        % Button pushed function: GenerateTemplateButton
        function GenerateTemplateButtonPushed(app, event)
            app.WaveletVectorEditField.Value = '';
            range = round(app.UIAxesWavelet.XAxis.Limits);
            app.Wavelet = app.PlottedData(range(1):range(2));
            tmp = num2str(app.Wavelet);
            app.WaveletVectorEditField.Value = regexprep(tmp,' +',' ');
            createTimingVector(app);
        end

        % Button pushed function: LoadCommentsButton
        function LoadCommentsButtonPushed(app, event)
            [file,path] = uigetfile('*.nev');
            figure(app.EpochingModuleUIFigure)
            if file==0
                return
            else
                app.commentFilename = file;
                app.commentFilepath = path;
            end
            loadComments(app)
            createTimingVector(app);
        end

        % Button pushed function: ResetCommentsButton
        function ResetCommentsButtonPushed(app, event)
            app.UITableComments.Data = table(string(app.Comments),app.CommentTimings);
            app.CommentSearchBar.Value = '';
            createTimingVector(app);
        end

        % Cell selection callback: UITableComments
        function UITableCommentsCellSelection(app, event)
            app.CommentTableIndex = event.Indices(:,1);
        end

        % Button pushed function: RemoveCommentButton
        function RemoveCommentButtonPushed(app, event)
            app.UITableComments.Data(app.CommentTableIndex,:)=[];
            createTimingVector(app);
        end

        % Value changing function: CommentSearchBar
        function CommentSearchBarValueChanging(app, event)
            commentSearch = event.Value;
            if isempty(commentSearch)
                app.UITableComments.Data = table(app.Comments,app.CommentTimings);
                return
            end
            found = ~cellfun(@isempty,regexp(app.Comments,commentSearch,'once'));
            app.UITableComments.Data = table(app.Comments(found),app.CommentTimings(found));
        end

        % Value changed function: CommentSearchBar
        function CommentSearchBarValueChanged(app, event)
            createTimingVector(app);
        end

        % Value changed function: MarkerLabelEditField
        function MarkerLabelEditFieldValueChanged(app, event)
%             if isempty(app.MarkerLabelEditField.Value)
%                 app.MarkerLabelEditField.Value = 'Marker Label';
%                 app.MarkerLabelEditField.FontColor = [0.8 0.8 0.8];
%             end
        end

        % Button pushed function: MarkerColorButton
        function MarkerColorButtonPushed(app, event)
            app.RGB = uisetcolor();
            app.ColorIndicator.BackgroundColor = app.RGB;
            figure(app.EpochingModuleUIFigure)
        end

        % Button pushed function: AddMarkerButton
        function AddMarkerButtonPushed(app, event)
            index = length(app.storedTimingVectors);
            if length(app.UIAxes.Children)>(1+index)
                delete(app.UIAxes.Children(1:end-(1+index)))
            end
            if ~isempty(app.MarkerLabelEditField.Value)
                app.currentTimingVectorMethod = app.MarkerLabelEditField.Value;
            end
            app.currentTimingVectorMarker = {app.MarkerDropDown.Value, app.RGB};
            createTimingVector(app)
            app.storedTimingVectors(index+1).Label = app.currentTimingVectorMethod;
            app.storedTimingVectors(index+1).Data = app.currentTimingVector;
            app.storedTimingVectors(index+1).Marker = app.MarkerDropDown.Value;
            app.storedTimingVectors(index+1).MarkerEdgeColor = app.RGB;
            app.currentTimingVectorMarker = [];
            app.MarkerListBox.Items = {app.storedTimingVectors(:).Label}';
            app.MarkerListBox.ItemsData = 1:length(app.MarkerListBox.Items);
            if ~isempty(app.storedTimingVectors)
                app.RemoveMarkerButton.Enable = 1;
            else
                app.RemoveMarkerButton.Enable = 0;
            end
        end

        % Button pushed function: RemoveMarkerButton
        function RemoveMarkerButtonPushed(app, event)
            app.MarkerListBox.Items(app.MarkerListBox.Value)=[];
            app.MarkerListBox.ItemsData = 1:length(app.MarkerListBox.Items);
            delete(app.UIAxes.Children(end-app.MarkerListBox.Value))
            app.storedTimingVectors(app.MarkerListBox.Value)=[];
        end

        % Button pushed function: RemapMarkersButton
        function RemapMarkersButtonPushed(app, event)
            app.RemapMarkersButton.Enable = 'off';
            [ind1,tf] = listdlg('PromptString',{'Select the set of markers you',' would like to use for remapping.'},'ListString',app.MarkerListBox.Items,'SelectionMode','single');
            if tf==0
                app.RemapMarkersButton.Enable = 'on';
            else
                MapStr = app.MarkerListBox.Items{ind1};
                [ind2,tf] = listdlg('PromptString',{'Select the set of markers you',' would like to use as anchor points.'},'ListString',app.MarkerListBox.Items,'SelectionMode','single');
                
                if tf==0
                    app.RemapMarkersButton.Enable = 'on';
                else
                    AnchorStr = app.MarkerListBox.Items{ind2};
                    MapVector = double(app.storedTimingVectors(ind1).Data(:));
                    AnchorVector = double(app.storedTimingVectors(ind2).Data(:));
                    DiffMat = MapVector-AnchorVector';
                    
                    minMapDur = min(abs(DiffMat),[],2);
                    minMapDur = minMapDur(:);
                    minMapDurIdx = cell(length(minMapDur),1);
                    map1 = [];
                    for i=1:length(minMapDur)
                        minMapDurIdx{i} = find(abs(DiffMat(i,:))==minMapDur(i),1);
                            q=min(abs(DiffMat(:,minMapDurIdx{i})));
                            if q==minMapDur(i)
                                r = find(abs(DiffMat(:,minMapDurIdx{i}))==q);
                                for k=1:length(r)
                                    if r(k)==i
                                        tmp = [minMapDurIdx{i} i];
                                        map1 = [map1;tmp];
                                    end
                                end
                            end
                    end
                    
                end
                
            end
            
            app.RemapMarkersButton.Enable = 'on';
            
            app.currentTimingVector = AnchorVector(map1(:,1));
            if isempty(app.currentTimingVector)
                return
            end
            app.currentTimingVectorMethod = [MapStr,' -> ',AnchorStr];
            
            app.TimingVectorLengthEditField.Value = length(app.currentTimingVector);


            index = length(app.storedTimingVectors);
            if length(app.UIAxes.Children)>(1+index)
                delete(app.UIAxes.Children(1:end-(1+index)))
            end
%             if ~isempty(app.MarkerLabelEditField.Value)
%                 app.currentTimingVectorMethod = app.MarkerLabelEditField.Value;
%             end
            app.currentTimingVectorMarker = {app.MarkerDropDown.Value, app.RGB};
            app.storedTimingVectors(index+1).Label = app.currentTimingVectorMethod;
            app.storedTimingVectors(index+1).Data = app.currentTimingVector;
            app.storedTimingVectors(index+1).Marker = app.MarkerDropDown.Value;
            app.storedTimingVectors(index+1).MarkerEdgeColor = app.RGB;
            
            refreshPlotMarker(app)

            app.currentTimingVectorMarker = [];
            app.MarkerListBox.Items = {app.storedTimingVectors(:).Label}';
            app.MarkerListBox.ItemsData = 1:length(app.MarkerListBox.Items);
            if ~isempty(app.storedTimingVectors)
                app.RemoveMarkerButton.Enable = 1;
            else
                app.RemoveMarkerButton.Enable = 0;
            end


        end

        % Button pushed function: ShowHideMarkerButton
        function ShowHideMarkerButtonPushed(app, event)
            if isempty(app.MarkerListBox.Value)
                return
            else
                index = length(app.UIAxes.Children)-app.MarkerListBox.Value;
                switch app.UIAxes.Children(end-app.MarkerListBox.Value).Visible
                    case 'on'
                        set(app.UIAxes.Children(index),'Visible','off');
                    case 'off'
                        set(app.UIAxes.Children(index),'Visible','on');
                end
            end
        end

        % Button pushed function: ExportMarkerButton
        function ExportMarkerButtonPushed(app, event)
            if isempty(app.SavePath)
                [file,app.SavePath] = uiputfile('*.mat');
            else
                [file,app.SavePath] = uiputfile(fullfile(app.SavePath,'*.mat'));
            end
            figure(app.EpochingModuleUIFigure)
            if file==0
                return
            end
            tmp = app.storedTimingVectors(app.MarkerListBox.Value(1)).Data;
            Epoch.TimingVector = round(tmp/(app.InputSampleRateEditField.Value/app.OutputSampleRateEditField.Value));
            Epoch.SamplingFreq = app.OutputSampleRateEditField.Value;
            save(fullfile(app.SavePath,file),'-struct','Epoch');
        end

        % Value changed function: MarkerListBox
        function MarkerListBoxValueChanged(app, event)
            if isempty(app.MarkerListBox.Value)
                set(app.UIAxes.Children,'LineWidth',0.5)
                app.ExportMarkerButton.Enable = 0;
            else
                index = false(length(app.UIAxes.Children),1);
                index(length(app.UIAxes.Children)-app.MarkerListBox.Value) = 1;
                set(app.UIAxes.Children(index),'LineWidth',1.0)
                set(app.UIAxes.Children(~index),'LineWidth',0.5)
                app.ExportMarkerButton.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EpochingModuleUIFigure = uifigure('Visible', 'off');
            app.EpochingModuleUIFigure.Name = 'Epoching Module';
            app.LoadDataButton = uibutton(app.EpochingModuleUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DropDown = uidropdown(app.EpochingModuleUIFigure);
            app.DropDown.Items = {'No Data'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'No Data';
            app.TabGroup2 = uitabgroup(app.EpochingModuleUIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.BasicSignalProcessingTab = uitab(app.TabGroup2);
            app.BasicSignalProcessingTab.Title = 'Basic Signal Processing';
            app.CleanPlotButton = uibutton(app.BasicSignalProcessingTab, 'push');
            app.CleanPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CleanPlotButtonPushed, true);
            app.CleanPlotButton.Text = 'Clean Plot';
            app.ResetPreprocessingButton = uibutton(app.BasicSignalProcessingTab, 'push');
            app.ResetPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPreprocessingButtonPushed, true);
            app.ResetPreprocessingButton.Text = 'Reset';
            app.StoreButton = uibutton(app.BasicSignalProcessingTab, 'push');
            app.StoreButton.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPushed, true);
            app.StoreButton.Text = 'Store';
            app.UITablePreprocessing = uitable(app.BasicSignalProcessingTab);
            app.UITablePreprocessing.ColumnName = {'Pre-processing Steps'};
            app.UITablePreprocessing.RowName = {};
            app.PreprocessingNameEditFieldLabel = uilabel(app.BasicSignalProcessingTab);
            app.PreprocessingNameEditFieldLabel.Text = 'Pre-processing Name';
            app.PreprocessingNameEditField = uieditfield(app.BasicSignalProcessingTab, 'text');
            app.DemeanPanel = uipanel(app.BasicSignalProcessingTab);
            app.DemeanPanel.Title = 'Demean';
            app.PlotDemeanButton = uibutton(app.DemeanPanel, 'push');
            app.PlotDemeanButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDemeanButtonPushed, true);
            app.PlotDemeanButton.Text = 'Plot';
            app.SetDemeanButton = uibutton(app.DemeanPanel, 'push');
            app.SetDemeanButton.ButtonPushedFcn = createCallbackFcn(app, @SetDemeanButtonPushed, true);
            app.SetDemeanButton.Text = 'Set';
            app.DetrendPanel = uipanel(app.BasicSignalProcessingTab);
            app.DetrendPanel.Title = 'Detrend';
            app.PlotDetrendButton = uibutton(app.DetrendPanel, 'push');
            app.PlotDetrendButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDetrendButtonPushed, true);
            app.PlotDetrendButton.Text = 'Plot';
            app.SetDetrendButton = uibutton(app.DetrendPanel, 'push');
            app.SetDetrendButton.ButtonPushedFcn = createCallbackFcn(app, @SetDetrendButtonPushed, true);
            app.SetDetrendButton.Text = 'Set';
            app.DifferencePanel = uipanel(app.BasicSignalProcessingTab);
            app.DifferencePanel.Title = 'Difference';
            app.PlotDifferenceButton = uibutton(app.DifferencePanel, 'push');
            app.PlotDifferenceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDifferenceButtonPushed, true);
            app.PlotDifferenceButton.Text = 'Plot';
            app.SetDifferenceButton = uibutton(app.DifferencePanel, 'push');
            app.SetDifferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SetDifferenceButtonPushed, true);
            app.SetDifferenceButton.Text = 'Set';
            app.OrderDifferenceSpinnerLabel = uilabel(app.DifferencePanel);
            app.OrderDifferenceSpinnerLabel.Text = 'Order';
            app.OrderDifferenceSpinner = uispinner(app.DifferencePanel);
            app.OrderDifferenceSpinner.Limits = [1 Inf];
            app.OrderDifferenceSpinner.Value = 1;
            app.FlipPanel = uipanel(app.BasicSignalProcessingTab);
            app.FlipPanel.Title = 'Flip';
            app.SetFlipButton = uibutton(app.FlipPanel, 'push');
            app.SetFlipButton.ButtonPushedFcn = createCallbackFcn(app, @SetFlipButtonPushed, true);
            app.SetFlipButton.Text = 'Set';
            app.PlotFlipButton = uibutton(app.FlipPanel, 'push');
            app.PlotFlipButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFlipButtonPushed, true);
            app.PlotFlipButton.Text = 'Plot';
            app.GradientPanel = uipanel(app.BasicSignalProcessingTab);
            app.GradientPanel.Title = 'Gradient';
            app.PlotGradientButton = uibutton(app.GradientPanel, 'push');
            app.PlotGradientButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGradientButtonPushed, true);
            app.PlotGradientButton.Text = 'Plot';
            app.SetGradientButton = uibutton(app.GradientPanel, 'push');
            app.SetGradientButton.ButtonPushedFcn = createCallbackFcn(app, @SetGradientButtonPushed, true);
            app.SetGradientButton.Text = 'Set';
            app.OrderGradientSpinnerLabel = uilabel(app.GradientPanel);
            app.OrderGradientSpinnerLabel.Text = 'Order';
            app.OrderGradientSpinner = uispinner(app.GradientPanel);
            app.OrderGradientSpinner.Limits = [1 Inf];
            app.OrderGradientSpinner.Value = 1;
            app.HaarDecompPanel = uipanel(app.BasicSignalProcessingTab);
            app.HaarDecompPanel.Title = 'Haar Decomp';
            app.PlotHaarButton = uibutton(app.HaarDecompPanel, 'push');
            app.PlotHaarButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHaarButtonPushed, true);
            app.PlotHaarButton.Text = 'Plot';
            app.SetHaarButton = uibutton(app.HaarDecompPanel, 'push');
            app.SetHaarButton.ButtonPushedFcn = createCallbackFcn(app, @SetHaarButtonPushed, true);
            app.SetHaarButton.Text = 'Set';
            app.OrderHaarSpinnerLabel = uilabel(app.HaarDecompPanel);
            app.OrderHaarSpinnerLabel.Text = 'Order';
            app.OrderHaarSpinner = uispinner(app.HaarDecompPanel);
            app.OrderHaarSpinner.Limits = [1 Inf];
            app.OrderHaarSpinner.Value = 1;
            app.TimingTab = uitab(app.TabGroup2);
            app.TimingTab.Title = 'Timing';
            app.TabGroup = uitabgroup(app.TimingTab);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ThresholdTab = uitab(app.TabGroup);
            app.ThresholdTab.Title = 'Threshold';
            app.ThresholdingButtonGroup = uibuttongroup(app.ThresholdTab);
            app.ThresholdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingButtonGroupSelectionChanged, true);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.MeanButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.MeanButton.Text = 'Mean + ';
            app.MeanButton.Value = true;
            app.CustomButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.StdEditFieldLabel = uilabel(app.ThresholdingButtonGroup);
            app.StdEditFieldLabel.Text = '* Std';
            app.StdEditField = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.StdEditField.ValueChangedFcn = createCallbackFcn(app, @StdEditFieldValueChanged, true);
            app.StdEditField.Value = 1.96;
            app.CustomThresholdEditField = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.CustomThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @CustomThresholdEditFieldValueChanged, true);
            app.TypeDropDownLabel = uilabel(app.ThresholdingButtonGroup);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.ThresholdingButtonGroup);
            app.TypeDropDown.Items = {'Absolute', 'Positive', 'Negative'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Absolute';
            app.FirstPointPerCrossingCheckBox = uicheckbox(app.ThresholdingButtonGroup);
            app.FirstPointPerCrossingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FirstPointPerCrossingCheckBoxValueChanged, true);
            app.FirstPointPerCrossingCheckBox.Text = 'First Point Per Crossing';
            app.UIAxesThreshold = uiaxes(app.ThresholdTab);
            title(app.UIAxesThreshold, 'Title')
            xlabel(app.UIAxesThreshold, 'X')
            app.FindPeaksTab = uitab(app.TabGroup);
            app.FindPeaksTab.Title = 'FindPeaks';
            app.NumberofPeaksCheckBox = uicheckbox(app.FindPeaksTab);
            app.NumberofPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @NumberofPeaksCheckBoxValueChanged, true);
            app.NumberofPeaksCheckBox.Text = 'Number of Peaks';
            app.MinimumPeakHeightCheckBox = uicheckbox(app.FindPeaksTab);
            app.MinimumPeakHeightCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakHeightCheckBoxValueChanged, true);
            app.MinimumPeakHeightCheckBox.Text = 'Minimum Peak Height';
            app.MinimumPeakProminenceCheckBox = uicheckbox(app.FindPeaksTab);
            app.MinimumPeakProminenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakProminenceCheckBoxValueChanged, true);
            app.MinimumPeakProminenceCheckBox.Text = 'Minimum Peak Prominence';
            app.ThresholdCheckBox = uicheckbox(app.FindPeaksTab);
            app.ThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThresholdCheckBoxValueChanged, true);
            app.ThresholdCheckBox.Text = 'Threshold';
            app.MinimumPeakDistanceCheckBox = uicheckbox(app.FindPeaksTab);
            app.MinimumPeakDistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakDistanceCheckBoxValueChanged, true);
            app.MinimumPeakDistanceCheckBox.Text = 'Minimum Peak Distance';
            app.MinimumPeakWidthCheckBox = uicheckbox(app.FindPeaksTab);
            app.MinimumPeakWidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakWidthCheckBoxValueChanged, true);
            app.MinimumPeakWidthCheckBox.Text = 'Minimum Peak Width';
            app.MaximumPeakWidthCheckBox = uicheckbox(app.FindPeaksTab);
            app.MaximumPeakWidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaximumPeakWidthCheckBoxValueChanged, true);
            app.MaximumPeakWidthCheckBox.Text = 'Maximum Peak Width';
            app.NumberofPeaksEditField = uieditfield(app.FindPeaksTab, 'numeric');
            app.NumberofPeaksEditField.Limits = [1 Inf];
            app.NumberofPeaksEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPeaksEditFieldValueChanged, true);
            app.NumberofPeaksEditField.Value = 1;
            app.MinimumPeakHeightEditField = uieditfield(app.FindPeaksTab, 'numeric');
            app.MinimumPeakHeightEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakHeightEditFieldValueChanged, true);
            app.MinimumPeakHeightEditField.Value = -Inf;
            app.MinimumPeakProminenceEditField = uieditfield(app.FindPeaksTab, 'numeric');
            app.MinimumPeakProminenceEditField.Limits = [0 Inf];
            app.MinimumPeakProminenceEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakProminenceEditFieldValueChanged, true);
            app.ThresholdEditField = uieditfield(app.FindPeaksTab, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.MinimumPeakDistance = uieditfield(app.FindPeaksTab, 'numeric');
            app.MinimumPeakDistance.Limits = [0 Inf];
            app.MinimumPeakDistance.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakDistanceValueChanged, true);
            app.MinimumPeakWidth = uieditfield(app.FindPeaksTab, 'numeric');
            app.MinimumPeakWidth.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakWidthValueChanged, true);
            app.MinimumPeakWidth.Value = Inf;
            app.MaximumPeakWidth = uieditfield(app.FindPeaksTab, 'numeric');
            app.MaximumPeakWidth.ValueChangedFcn = createCallbackFcn(app, @MaximumPeakWidthValueChanged, true);
            app.WaveletsTab = uitab(app.TabGroup);
            app.WaveletsTab.Title = 'Wavelets';
            app.MethodDropDownLabel = uilabel(app.WaveletsTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.WaveletsTab);
            app.MethodDropDown.Items = {'Manual', 'Template', ''};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Manual';
            app.GenerateTemplateButton = uibutton(app.WaveletsTab, 'push');
            app.GenerateTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTemplateButtonPushed, true);
            app.GenerateTemplateButton.Enable = 'off';
            app.GenerateTemplateButton.Tooltip = {'Generates a wavelet representative of what is currently seen in the Wavelet plot window.'};
            app.GenerateTemplateButton.Text = 'Generate Template';
            app.WaveletVectorEditFieldLabel = uilabel(app.WaveletsTab);
            app.WaveletVectorEditFieldLabel.Text = 'Wavelet Vector';
            app.WaveletVectorEditField = uieditfield(app.WaveletsTab, 'text');
            app.WaveletVectorEditField.ValueChangedFcn = createCallbackFcn(app, @WaveletVectorEditFieldValueChanged, true);
            app.WaveletVectorEditField.Value = '0 1';
            app.UIAxesWavelet = uiaxes(app.WaveletsTab);
            title(app.UIAxesWavelet, 'Wavelet')
            app.CommentsTab = uitab(app.TabGroup);
            app.CommentsTab.Title = 'Comments';
            app.UITableComments = uitable(app.CommentsTab);
            app.UITableComments.ColumnName = {'Comment'; 'Timestamp'};
            app.UITableComments.RowName = {};
            app.UITableComments.CellSelectionCallback = createCallbackFcn(app, @UITableCommentsCellSelection, true);
            app.RemoveCommentButton = uibutton(app.CommentsTab, 'push');
            app.RemoveCommentButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCommentButtonPushed, true);
            app.RemoveCommentButton.Text = 'Remove Comment';
            app.ResetCommentsButton = uibutton(app.CommentsTab, 'push');
            app.ResetCommentsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCommentsButtonPushed, true);
            app.ResetCommentsButton.Text = 'Reset';
            app.LoadCommentsButton = uibutton(app.CommentsTab, 'push');
            app.LoadCommentsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCommentsButtonPushed, true);
            app.LoadCommentsButton.Text = 'Load';
            app.CommentSearchBar = uieditfield(app.CommentsTab, 'text');
            app.CommentSearchBar.ValueChangedFcn = createCallbackFcn(app, @CommentSearchBarValueChanged, true);
            app.CommentSearchBar.ValueChangingFcn = createCallbackFcn(app, @CommentSearchBarValueChanging, true);
            app.MarkerControlPanel = uipanel(app.TimingTab);
            app.MarkerControlPanel.Title = 'Marker Control';
            app.MarkerDropDown = uidropdown(app.MarkerControlPanel);
            app.MarkerDropDown.Items = {'Circle', 'Plus Sign', 'Asterisk', 'Point', 'Cross', 'Square', 'Diamond', 'Upward-pointing Triangle', 'Downward-pointing Triangle', 'Left-pointing Triangle', 'Right-pointing Triangle', 'Pentagram', 'Hexagram'};
            app.MarkerDropDown.ItemsData = {'o', '+', '*', '.', 'x', 's', 'd', '^', 'v', '<', '>', 'p', 'h'};
            app.MarkerDropDown.Value = 'o';
            app.AddMarkerButton = uibutton(app.MarkerControlPanel, 'push');
            app.AddMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @AddMarkerButtonPushed, true);
            app.AddMarkerButton.Text = 'Add';
            app.RemoveMarkerButton = uibutton(app.MarkerControlPanel, 'push');
            app.RemoveMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMarkerButtonPushed, true);
            app.RemoveMarkerButton.Enable = 'off';
            app.RemoveMarkerButton.Text = 'Remove';
            app.MarkerLabelEditField = uieditfield(app.MarkerControlPanel, 'text');
            app.MarkerLabelEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerLabelEditFieldValueChanged, true);
            app.ShowHideMarkerButton = uibutton(app.MarkerControlPanel, 'push');
            app.ShowHideMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHideMarkerButtonPushed, true);
            app.ShowHideMarkerButton.Text = 'Show/Hide';
            app.ExportMarkerButton = uibutton(app.MarkerControlPanel, 'push');
            app.ExportMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMarkerButtonPushed, true);
            app.ExportMarkerButton.Enable = 'off';
            app.ExportMarkerButton.Text = 'Export';
            app.MarkerColorButton = uibutton(app.MarkerControlPanel, 'push');
            app.MarkerColorButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerColorButtonPushed, true);
            app.MarkerColorButton.Text = 'Color';
            app.ColorIndicator = uilabel(app.MarkerControlPanel);
            app.ColorIndicator.Text = '';
            app.TimingVectorLengthEditFieldLabel = uilabel(app.MarkerControlPanel);
            app.TimingVectorLengthEditFieldLabel.Text = 'Timing Vector Length';
            app.TimingVectorLengthEditField = uieditfield(app.MarkerControlPanel, 'numeric');
            app.TimingVectorLengthEditField.ValueDisplayFormat = '%.0f';
            app.TimingVectorLengthEditField.Editable = 'off';
            app.RemapMarkersButton = uibutton(app.MarkerControlPanel, 'push');
            app.RemapMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @RemapMarkersButtonPushed, true);
            app.RemapMarkersButton.Text = 'Remap';
            app.MarkerListPanel = uipanel(app.TimingTab);
            app.MarkerListPanel.Title = 'Marker List';
            app.MarkerListBox = uilistbox(app.MarkerListPanel);
            app.MarkerListBox.Items = {};
            app.MarkerListBox.Multiselect = 'on';
            app.MarkerListBox.ValueChangedFcn = createCallbackFcn(app, @MarkerListBoxValueChanged, true);
            app.MarkerListBox.Value = {};
            app.DatasetsDropDownLabel = uilabel(app.EpochingModuleUIFigure);
            app.DatasetsDropDownLabel.Text = 'Datasets';
            app.DatasetsDropDown = uidropdown(app.EpochingModuleUIFigure);
            app.DatasetsDropDown.Items = {'Raw'};
            app.DatasetsDropDown.ItemsData = {'1'};
            app.DatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetsDropDownValueChanged, true);
            app.DatasetsDropDown.Value = '1';
            app.RefreshButton = uibutton(app.EpochingModuleUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.InputSampleRateEditFieldLabel = uilabel(app.EpochingModuleUIFigure);
            app.InputSampleRateEditFieldLabel.Text = 'Input Sample Rate';
            app.InputSampleRateEditField = uieditfield(app.EpochingModuleUIFigure, 'numeric');
            app.InputSampleRateEditField.Limits = [0 Inf];
            app.InputSampleRateEditField.ValueDisplayFormat = '%.0f';
            app.InputSampleRateEditField.Value = 30000;
            app.OutputSampleRateEditFieldLabel = uilabel(app.EpochingModuleUIFigure);
            app.OutputSampleRateEditFieldLabel.Text = 'Output Sample Rate';
            app.OutputSampleRateEditField = uieditfield(app.EpochingModuleUIFigure, 'numeric');
            app.OutputSampleRateEditField.Limits = [0 Inf];
            app.OutputSampleRateEditField.ValueDisplayFormat = '%.0f';
            app.OutputSampleRateEditField.Value = 2000;
            app.DeleteButton = uibutton(app.EpochingModuleUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.UIAxes = uiaxes(app.EpochingModuleUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            % Show the figure after all components are created
            app.EpochingModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimingModule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EpochingModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EpochingModuleUIFigure)
        end
    end
end