classdef TimingModuleLITE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadDatasetsMenu                matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        AutomaticallyNameFilesCheckBox  matlab.ui.control.CheckBox
        SaveAsEditField                 matlab.ui.control.EditField
        SaveAsLabel                     matlab.ui.control.Label
        FirstPointPerCrossingCheckBox   matlab.ui.control.CheckBox
        DeleteButton                    matlab.ui.control.Button
        MarkerTimestampsListBox         matlab.ui.control.ListBox
        MarkerTimestampsLabel           matlab.ui.control.Label
        SaveandContinueButton           matlab.ui.control.Button
        OutputSampleRateEditField       matlab.ui.control.NumericEditField
        OutputSampleRateEditFieldLabel  matlab.ui.control.Label
        InputSampleRateEditField        matlab.ui.control.NumericEditField
        InputSampleRateEditFieldLabel   matlab.ui.control.Label
        MarkerTotalEditField            matlab.ui.control.NumericEditField
        MarkerTotalEditFieldLabel       matlab.ui.control.Label
        ThresholdDropDown               matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Filepath
        Filename
        FileExt = '.ns5'
        Files
        Savepath
        SaveFilename
        Index = 1
        Data
        
        Threshold
        TimingVector
        StoredTimingVector
        
        ReorderingVector
        HighlightedMarkersIndex
    end
    
    properties (Access = public)
        ReorderDataApp
    end
    
    methods (Access = private)
        
        function selectFiles(app)
            app.Index = 1;
            [filenames,app.Filepath] = uigetfile('*.*','Multiselect','on');
            figure(app.UIFigure)
            
            if iscell(filenames)
                [~,~,fileExts] = cellfun(@fileparts,filenames,'Uni',0);
                fileExts = unique(fileExts);
                if length(fileExts)>1
                    idx = listdlg('ListString',fileExts,'PromptString','Select the desired file extension','SelectionMode','single');
                    app.FileExt = fileExts{idx};
                else
                    app.FileExt = fileExts{:};
                end
                idx1 = contains(filenames,app.FileExt);
                app.Files = filenames(idx1);
            elseif ischar(filenames)
                app.Files = filenames;
            else
                choice = uiconfirm(app.UIFigure,'Files containing the requested info not detected.','Error','Options',{'Retry','Close'},'Icon','error');
                switch choice
                    case 'Retry'
                        selectFiles(app)
                    case 'Close'
                        UIFigureCloseRequest(app)
                end
            end
        end
        
        function selectDataset(app)
            resetData(app)
            if iscell(app.Files)
                [app.Filepath,app.Filename] = fileparts(fullfile(app.Filepath,app.Files(app.Index)));
            else
                [app.Filepath,app.Filename] = fileparts(fullfile(app.Filepath,app.Files));
            end
            
            d=uiprogressdlg(app.UIFigure,'Title','Loading Blackrock Data','Indeterminate','on');
            NSx = openNSx(fullfile(app.Filepath,[app.Filename,app.FileExt]),'noread');
            app.InputSampleRateEditField.Value = NSx.MetaTags.SamplingFreq;
            tmp = {NSx.ElectrodesInfo.Label}';
%             tmp = regexprep(tmp,'\W','');
            app.ChannelDropDown.Items = tmp;
            app.ChannelDropDown.ItemsData = 1:length(app.ChannelDropDown.Items);
            close(d)
            loadData(app)
            if app.AutomaticallyNameFilesCheckBox.Value && ~isempty(app.Savepath)
                generateSaveName(app)
                app.SaveAsEditField.Value = fullfile(app.Savepath,app.SaveFilename);
            elseif ~isempty(app.Savepath)
                app.SaveFilename = [app.Filename,'.mat'];
                app.SaveAsEditField.Value = fullfile(app.Savepath,app.SaveFilename);
            end
        end
        
        function resetData(app)
            app.Data = [];
            app.TimingVector = [];
            app.StoredTimingVector = [];
            app.MarkerTimestampsListBox.Items = {};
            app.HighlightedMarkersIndex = [];
            app.SaveAsEditField.Value = '';
            app.SaveFilename = [];
        end
        
        function loadData(app)
            d=uiprogressdlg(app.UIFigure,'Title',sprintf('Loading Signal: %s',app.ChannelDropDown.Items{app.ChannelDropDown.Value}),'Indeterminate','on');
            NSx = openNSx(fullfile(app.Filepath,[app.Filename,app.FileExt]),['c:' int2str(app.ChannelDropDown.Value)]);
            NSx = processSingleNSx(NSx);
            app.Data = double(NSx.Data).*NSx.ElectrodesInfo.Resolution;
            close(d)
            plot(app.UIAxes,app.Data,'PickableParts','none','Tag','Data')
            title(app.UIAxes, [app.ChannelDropDown.Items{app.ChannelDropDown.Value}])
            xlabel(app.UIAxes,'Time')
            if ~isempty(app.TimingVector)
                hold(app.UIAxes,'on')
                plot(app.UIAxes,app.TimingVector,app.Data(app.TimingVector),'LineStyle','none','Marker','+','MarkerEdgeColor','r','Tag','Markers');
                hold(app.UIAxes,'off')
            end
        end
        
        function generateSaveName(app)
            app.SaveFilename = [];
            p = parseBIDSfilename(app.Filename);
            list = fieldnames(p);
            if isempty(app.ReorderingVector)
                app.ReorderDataApp = ReorderList(list,app);
                waitfor(app.ReorderDataApp)
            end
            for i = 1:length(app.ReorderingVector)
                app.SaveFilename = [app.SaveFilename,'_',p.(list{app.ReorderingVector(i)})];
            end
            app.SaveFilename(1) = [];
            app.SaveFilename = [app.SaveFilename,'.mat'];
        end
        
        function createTimingVector(app)
            app.Threshold = app.ThresholdEditField.Value;
            switch app.ThresholdDropDown.Value
                case 'Absolute'
                    app.TimingVector = find(abs(app.Data)>app.Threshold);
                case 'Inverse Absolute'
                    app.TimingVector = find(abs(app.Data)<app.Threshold);
                case 'Positive'
                    app.TimingVector = find(app.Data>app.Threshold);
                case 'Negative'
                    app.TimingVector = find(app.Data<app.Threshold);
            end
            try
                if app.FirstPointPerCrossingCheckBox.Value
                    tmp = find(diff(app.TimingVector)>1);
                    app.TimingVector = app.TimingVector([1 tmp+1]);
                end
            catch
                app.TimingVector = [];
            end
            
            thresholdPlot(app);
            app.StoredTimingVector = app.TimingVector;
            app.MarkerTimestampsListBox.Items = cellstr(int2str(app.StoredTimingVector(:)));
            app.MarkerTimestampsListBox.ItemsData = 1:length(app.StoredTimingVector);
            app.MarkerTotalEditField.Value = length(app.StoredTimingVector);
            refreshPlotMarker(app)
        end
        
        function thresholdPlot(app)
            ind = find(isgraphics(app.UIAxes.Children,'ConstantLine'));
            if ~isempty(ind)
                app.UIAxes.Children(ind).Value = app.Threshold;
            else
                hold(app.UIAxes,'on')
                yline(app.UIAxes,app.Threshold,'k','Tag','Threshold')
                hold(app.UIAxes,'off')
            end
        end
        
        function refreshPlotMarker(app)
            ind = [];
            for i = 1:length(app.UIAxes.Children)
                if contains(app.UIAxes.Children(i).Tag,'Markers')
                    ind = [ind i];
                end
            end
            
            if ~isempty(ind)
                delete(app.UIAxes.Children(ind))
            end
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.StoredTimingVector,app.Data(app.StoredTimingVector),'LineStyle','none','Marker','+','MarkerEdgeColor',[1 0 0],'Tag','Markers');
            hold(app.UIAxes,'off')
        end
        
        function highlightMarkers(app)
            x = str2double(app.MarkerTimestampsListBox.Items(app.HighlightedMarkersIndex));
            y = app.Data(x);
            ind = [];
            for i = 1:length(app.UIAxes.Children)
                if contains(app.UIAxes.Children(i).Tag,'HighlightedMarkers')
                    ind = [ind i];
                end
            end
            
            if ~isempty(ind)
                delete(app.UIAxes.Children(ind))
            end
            hold(app.UIAxes,'on')
            plot(app.UIAxes,x,y,'LineStyle','none','Marker','*','MarkerEdgeColor','m','Tag','HighlightedMarkers');
            hold(app.UIAxes,'off')
        end
        
    end
    
    methods (Access = public)
        function setReorderListVariable(app,reorderingVector)
            app.ReorderingVector = reorderingVector;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';
            
            selectFiles(app)
            selectDataset(app)
            createTimingVector(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app.ReorderDataApp)
            catch
            end
            delete(app)
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            loadData(app)
            createTimingVector(app)
        end

        % Value changed function: ThresholdDropDown
        function ThresholdDropDownValueChanged(app, event)
            createTimingVector(app)
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            createTimingVector(app)
        end

        % Value changed function: FirstPointPerCrossingCheckBox
        function FirstPointPerCrossingCheckBoxValueChanged(app, event)
            createTimingVector(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.StoredTimingVector(app.MarkerTimestampsListBox.Value)=[];
            app.MarkerTimestampsListBox.Items(app.MarkerTimestampsListBox.Value)=[];
            app.MarkerTimestampsListBox.ItemsData = 1:length(app.MarkerTimestampsListBox.Items);
            refreshPlotMarker(app)
            app.MarkerTotalEditField.Value = length(app.StoredTimingVector);
        end

        % Button pushed function: SaveandContinueButton
        function SaveandContinueButtonPushed(app, event)
            app.Index = app.Index + 1;
            
            Epoch.TimingVector = round(app.StoredTimingVector/(app.InputSampleRateEditField.Value/app.OutputSampleRateEditField.Value));
            Epoch.SamplingFreq = app.OutputSampleRateEditField.Value;
            save(app.SaveAsEditField.Value,'-struct','Epoch')
            if app.Index<=length(app.Files)
                selectDataset(app)
                createTimingVector(app)
            else
                
                ok = uiconfirm(app.UIFigure,'All selected files have been processed. Would you like to quit?','Job Complete',...
                    'Options',{'Yes','No'},'DefaultOption',1,'CancelOption',2,'Icon','success');
                switch ok
                    case 'Yes'
                        UIFigureCloseRequest(app)
                    case 'No'
                        app.Index = 1;
                        resetData(app);
                end
            end
        end

        % Value changed function: MarkerTimestampsListBox
        function MarkerTimestampsListBoxValueChanged(app, event)
            app.HighlightedMarkersIndex = event.Value;
            highlightMarkers(app);
        end

        % Menu selected function: LoadDatasetsMenu
        function LoadDatasetsMenuSelected(app, event)
            selectFiles(app)
            selectDataset(app)
            createTimingVector(app)
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [app.SaveFilename,app.Savepath] = uiputfile();
            app.SaveAsEditField.Value = fullfile(app.Savepath,app.SaveFilename);
        end

        % Value changed function: AutomaticallyNameFilesCheckBox
        function AutomaticallyNameFilesCheckBoxValueChanged(app, event)
            value = app.AutomaticallyNameFilesCheckBox.Value;
            if isempty(app.Savepath)
                app.Savepath = uigetdir();
            end
            if value
                generateSaveName(app)
            else
                app.SaveFilename = [app.Filename,'.mat'];
            end
            app.SaveAsEditField.Value = fullfile(app.Savepath,app.SaveFilename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDatasetsMenu = uimenu(app.FileMenu);
            app.LoadDatasetsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDatasetsMenuSelected, true);
            app.LoadDatasetsMenu.Text = 'Load Datasets';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdDropDownLabel = uilabel(app.UIFigure);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.UIFigure);
            app.ThresholdDropDown.Items = {'Absolute', 'Inverse Absolute', 'Positive', 'Negative'};
            app.ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdDropDownValueChanged, true);
            app.ThresholdDropDown.Value = 'Absolute';
            app.MarkerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.MarkerTotalEditFieldLabel.Text = 'Marker Total';
            app.MarkerTotalEditField = uieditfield(app.UIFigure, 'numeric');
            app.MarkerTotalEditField.Editable = 'off';
            app.InputSampleRateEditFieldLabel = uilabel(app.UIFigure);
            app.InputSampleRateEditFieldLabel.Text = 'Input Sample Rate';
            app.InputSampleRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputSampleRateEditField.ValueDisplayFormat = '%.0f';
            app.InputSampleRateEditField.Editable = 'off';
            app.InputSampleRateEditField.Value = 30000;
            app.OutputSampleRateEditFieldLabel = uilabel(app.UIFigure);
            app.OutputSampleRateEditFieldLabel.Text = 'Output Sample Rate';
            app.OutputSampleRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputSampleRateEditField.ValueDisplayFormat = '%.0f';
            app.OutputSampleRateEditField.Value = 2000;
            app.SaveandContinueButton = uibutton(app.UIFigure, 'push');
            app.SaveandContinueButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandContinueButtonPushed, true);
            app.SaveandContinueButton.Text = 'Save and Continue';
            app.MarkerTimestampsLabel = uilabel(app.UIFigure);
            app.MarkerTimestampsLabel.Text = {'Marker'; 'Timestamps'};
            app.MarkerTimestampsListBox = uilistbox(app.UIFigure);
            app.MarkerTimestampsListBox.Items = {};
            app.MarkerTimestampsListBox.Multiselect = 'on';
            app.MarkerTimestampsListBox.ValueChangedFcn = createCallbackFcn(app, @MarkerTimestampsListBoxValueChanged, true);
            app.MarkerTimestampsListBox.Value = {};
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.FirstPointPerCrossingCheckBox = uicheckbox(app.UIFigure);
            app.FirstPointPerCrossingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FirstPointPerCrossingCheckBoxValueChanged, true);
            app.FirstPointPerCrossingCheckBox.Text = 'First Point Per Crossing';
            app.FirstPointPerCrossingCheckBox.Value = true;
            app.SaveAsLabel = uilabel(app.UIFigure);
            app.SaveAsLabel.Text = 'Save As:';
            app.SaveAsEditField = uieditfield(app.UIFigure, 'text');
            app.AutomaticallyNameFilesCheckBox = uicheckbox(app.UIFigure);
            app.AutomaticallyNameFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticallyNameFilesCheckBoxValueChanged, true);
            app.AutomaticallyNameFilesCheckBox.Text = 'Automatically Name Files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimingModuleLITE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end